classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        EmisorTab                       matlab.ui.container.Tab
        AudioCifradoPanel               matlab.ui.container.Panel
        CifrarLabel                     matlab.ui.control.Label
        Button_5                        matlab.ui.control.Button
        GraficarLabel                   matlab.ui.control.Label
        ReproducirLabel_2               matlab.ui.control.Label
        Button_4                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        AudioPanel                      matlab.ui.container.Panel
        Button                          matlab.ui.control.StateButton
        ReproducirLabel                 matlab.ui.control.Label
        GrabarLabel                     matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        SegundosSpinner                 matlab.ui.control.Spinner
        SegundosSpinnerLabel            matlab.ui.control.Label
        ControlemisordelmensajeLabel    matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        ReceptorTab                     matlab.ui.container.Tab
        AudiodescifradoPanel            matlab.ui.container.Panel
        GraficarLabel_2                 matlab.ui.control.Label
        ReproducirLabel_4               matlab.ui.control.Label
        Button_10                       matlab.ui.control.Button
        Button_9                        matlab.ui.control.Button
        CargaraudiocifradoPanel         matlab.ui.container.Panel
        descifrarLabel                  matlab.ui.control.Label
        Button_11                       matlab.ui.control.Button
        CargardatosLabel                matlab.ui.control.Label
        Button_8                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.StateButton
        ReproducirLabel_3               matlab.ui.control.Label
        ControlemisordelmensajeLabel_2  matlab.ui.control.Label
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        segundos = 0;
        y;
        yp;
        p = 13;
        q = 23;
        nval;
        original;
        m;
        e;
        n;
        c;
        d;
        z;
        eval;
        dval;
        mensajeCifrado;
        mensajeDescifrado;
        mensajeCifradoDownLoad;
    end
    
    methods (Access = private)

        function [e,d] = generarllaves(app)
            phi = (app.p-1)*(app.q-1);
            for e = 2:phi-1        %publico ( llave publica ) debe estar entre 1 < x < phi y gdc(phi,eval) ==1
                if gcd(phi,e) == 1
                    break
                end
            end
            for d = 2:phi-1
                if mod(e*d,phi) == 1
                    break
                end
            end
        end

        function plotOriginalVoice(app)
            plot(app.UIAxes,app.y);
            title(app.UIAxes,'Voz Original');
            xlabel(app.UIAxes,'Tiempo');
            ylabel(app.UIAxes,'Amplitud');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_8
        function UpLoadButtonPushed(app, event)
            en = uigetfile('*.xlsx');
            app.mensajeCifradoDownLoad = xlsread(en);
            app.z = uint8(app.mensajeCifradoDownLoad);
            plot(app.UIAxes_2,app.mensajeCifrado);
            title(app.UIAxes_2,'Voz Cifrada');
            xlabel(app.UIAxes_2,'Tiempo');
            ylabel(app.UIAxes_2,'Amplitud');
        end

        % Value changed function: SegundosSpinner
        function SegundosSpinnerValueChanged(app, event)
            app.segundos = app.SegundosSpinner.Value;
        end

        % Button pushed function: Button_2
        function buttonPushedGrabar(app, event)
            seg = app.segundos;
            rec = audiorecorder;
            msg1 = msgbox('recording...');
            recordblocking(rec,seg);
            delete(msg1);
            msg1 = msgbox('recording done!');
            app.y = getaudiodata(rec,'uint8');
            app.yp = getaudiodata(rec);
            app.original = double(app.y);
            xlswrite('RSA_original_voice.xlsx',app.original);
            plotOriginalVoice(app);
        end

        % Value changed function: Button
        function PlayButtonValueChanged(app, event)
            sound(app.yp,8000);
        end

        % Callback function
        function pEditFieldValueChanged(app, event)
            app.p = app.pEditField.Value;
        end

        % Callback function
        function qEditFieldValueChanged(app, event)
            app.q = app.qEditField.Value;
        end

        % Button pushed function: Button_5
        function cifrarButtonPushed(app, event)
            app.nval = app.p*app.q;         %publico
            [app.eval,app.dval] = generarllaves(app);
            app.mensajeCifrado = cifrar(app.original,app.eval,app.nval);
            xlswrite('RSA_encrypted_voice.xlsx',app.mensajeCifrado);
            audiowrite('cifrado.wav',uint8(app.mensajeCifrado),8000);
            msg1 = msgbox("Cifrado Con Exito");
        end

        % Button pushed function: Button_3
        function vistaCifradoButtonPushed(app, event)
            plot(app.UIAxes2,app.mensajeCifrado);
            title(app.UIAxes2,'Voz Cifrada');
            xlabel(app.UIAxes2,'Tiempo');
            ylabel(app.UIAxes2,'Amplitud');
        end

        % Callback function: Button_4, Button_7
        function playCifradoButtonPushed(app, event)
            [aux,Fs] = audioread('cifrado.wav');
            sound(aux,Fs);
        end

        % Callback function
        function Button_7ValueChanged(app, event)
            [aux,Fs] = audioread('cifrado.wav');
            sound(aux,Fs);
        end

        % Button pushed function: Button_11
        function descifradoButtonPushed(app, event)
            app.mensajeDescifrado = descifrar(app.mensajeCifrado,app.dval,app.nval);
            dec = uint8(app.mensajeDescifrado);
            xlswrite('RSA_decrypted_voice.xlsx',dec);
            audiowrite('descifrado.wav',uint8(app.mensajeDescifrado),8000);
            msg1 = msgbox("Descifrado Con Exito");
        end

        % Button pushed function: Button_9
        function DescifradoViewButtonPushed(app, event)
            plot(app.UIAxes2_2,app.mensajeDescifrado);
            title(app.UIAxes2_2,'Voz Descifrada');
            xlabel(app.UIAxes2_2,'Tiempo');
            ylabel(app.UIAxes2_2,'Amplitud');
        end

        % Button pushed function: Button_10
        function descifradoPlayButtonPushed(app, event)
            [aux,Fs] = audioread('descifrado.wav');
            sound(aux,Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EmisorTab = uitab(app.TabGroup);
            app.EmisorTab.Title = 'Emisor';
            app.UIAxes = uiaxes(app.EmisorTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.EmisorTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ControlemisordelmensajeLabel = uilabel(app.EmisorTab);
            app.ControlemisordelmensajeLabel.Text = 'Control emisor del mensaje';
            app.AudioPanel = uipanel(app.EmisorTab);
            app.AudioPanel.Title = 'Audio';
            app.SegundosSpinnerLabel = uilabel(app.AudioPanel);
            app.SegundosSpinnerLabel.Text = 'Segundos';
            app.SegundosSpinner = uispinner(app.AudioPanel);
            app.SegundosSpinner.Limits = [0 20];
            app.SegundosSpinner.ValueChangedFcn = createCallbackFcn(app, @SegundosSpinnerValueChanged, true);
            app.SegundosSpinner.Tag = 'segundos';
            app.Button_2 = uibutton(app.AudioPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @buttonPushedGrabar, true);
            app.Button_2.Tag = 'grabar';
            app.Button_2.Icon = fullfile(pathToMLAPP, 'microfono.png');
            app.Button_2.Text = '';
            app.GrabarLabel = uilabel(app.AudioPanel);
            app.GrabarLabel.Text = 'Grabar';
            app.ReproducirLabel = uilabel(app.AudioPanel);
            app.ReproducirLabel.Text = 'Reproducir';
            app.Button = uibutton(app.AudioPanel, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @PlayButtonValueChanged, true);
            app.Button.Tag = 'reproducirOriginal';
            app.Button.Text = '▶︎';
            app.AudioCifradoPanel = uipanel(app.EmisorTab);
            app.AudioCifradoPanel.Title = 'Audio Cifrado';
            app.Button_3 = uibutton(app.AudioCifradoPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @vistaCifradoButtonPushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'testigo.png');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.AudioCifradoPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @playCifradoButtonPushed, true);
            app.Button_4.Text = '▶︎';
            app.ReproducirLabel_2 = uilabel(app.AudioCifradoPanel);
            app.ReproducirLabel_2.Text = 'Reproducir';
            app.GraficarLabel = uilabel(app.AudioCifradoPanel);
            app.GraficarLabel.Text = 'Graficar';
            app.Button_5 = uibutton(app.AudioCifradoPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @cifrarButtonPushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'candado.png');
            app.Button_5.Text = '';
            app.CifrarLabel = uilabel(app.AudioCifradoPanel);
            app.CifrarLabel.Text = 'Cifrar';
            app.ReceptorTab = uitab(app.TabGroup);
            app.ReceptorTab.Title = 'Receptor';
            app.UIAxes_2 = uiaxes(app.ReceptorTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes2_2 = uiaxes(app.ReceptorTab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.ControlemisordelmensajeLabel_2 = uilabel(app.ReceptorTab);
            app.ControlemisordelmensajeLabel_2.Text = 'Control emisor del mensaje';
            app.CargaraudiocifradoPanel = uipanel(app.ReceptorTab);
            app.CargaraudiocifradoPanel.Title = 'Cargar audio cifrado';
            app.ReproducirLabel_3 = uilabel(app.CargaraudiocifradoPanel);
            app.ReproducirLabel_3.Text = 'Reproducir';
            app.Button_7 = uibutton(app.CargaraudiocifradoPanel, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @playCifradoButtonPushed, true);
            app.Button_7.Text = '▶︎';
            app.Button_8 = uibutton(app.CargaraudiocifradoPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @UpLoadButtonPushed, true);
            app.Button_8.Icon = fullfile(pathToMLAPP, 'subir.png');
            app.Button_8.Text = '';
            app.CargardatosLabel = uilabel(app.CargaraudiocifradoPanel);
            app.CargardatosLabel.Text = 'Cargar datos';
            app.Button_11 = uibutton(app.CargaraudiocifradoPanel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @descifradoButtonPushed, true);
            app.Button_11.Icon = fullfile(pathToMLAPP, 'candado.png');
            app.Button_11.Text = '';
            app.descifrarLabel = uilabel(app.CargaraudiocifradoPanel);
            app.descifrarLabel.Text = 'descifrar';
            app.AudiodescifradoPanel = uipanel(app.ReceptorTab);
            app.AudiodescifradoPanel.Title = 'Audio descifrado';
            app.Button_9 = uibutton(app.AudiodescifradoPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @DescifradoViewButtonPushed, true);
            app.Button_9.Icon = fullfile(pathToMLAPP, 'testigo.png');
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.AudiodescifradoPanel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @descifradoPlayButtonPushed, true);
            app.Button_10.Text = '▶︎';
            app.ReproducirLabel_4 = uilabel(app.AudiodescifradoPanel);
            app.ReproducirLabel_4.Text = 'Reproducir';
            app.GraficarLabel_2 = uilabel(app.AudiodescifradoPanel);
            app.GraficarLabel_2.Text = 'Graficar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end